classdef intersectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        deleteButton                  matlab.ui.control.Button
        clearpointButton              matlab.ui.control.Button
        exportButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        refreshButton                 matlab.ui.control.Button
        pointsnotcloserthanEditFieldLabel  matlab.ui.control.Label
        pointsnotcloserthanEditField  matlab.ui.control.NumericEditField
        calculateButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        size;
        rowNumber;
        intersectionTable;
        image;
        value;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgName, imgSize, IP)
            app.image = imgName;
            app.intersectionTable = [];
            for i = 1:length(IP)
                app.intersectionTable = [app.intersectionTable; IP(i,1) IP(i,2)];
            end
            C = num2cell(app.intersectionTable);
            tdata = cell2table(C,'VariableNames',{'X', 'Y'});
            img = imread(imgName);
            imshow(img, 'parent', app.UIAxes);
            hold(app.UIAxes, 'on')
            title(app.UIAxes, 'fiber results');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            plot(app.UIAxes,app.intersectionTable(:,1),app.intersectionTable(:,2),'b.','MarkerSize',15);
            app.UITable.Data = tdata;
            app.size = imgSize;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.rowNumber = event.Indices;
            x = app.intersectionTable(indices(1),1);
            y = app.intersectionTable(indices(1),2);
            plot(app.UIAxes,x,y,'r.','MarkerSize',15);
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
           indices = app.rowNumber;
           x = app.intersectionTable(indices(1),1);
           y = app.intersectionTable(indices(1),2);
           plot(app.UIAxes,x,y,'Marker','X','Color','blue','LineWidth',2,'MarkerSize',15);
           app.UITable.Data(indices(1),:) = [];
           app.intersectionTable(indices(1),:) = [];
        end

        % Button pushed function: clearpointButton
        function clearpointButtonPushed(app, event)
            indices = app.rowNumber;
            x = app.intersectionTable(indices(1),1);
            y = app.intersectionTable(indices(1),2);
            plot(app.UIAxes,x,y,'b.','MarkerSize',15);
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            hold(app.UIAxes,'off')
            C = num2cell(app.intersectionTable);
            tdata = cell2table(C,'VariableNames',{'X', 'Y'});
            img = imread(app.image);
            imshow(img, 'parent', app.UIAxes);
            hold(app.UIAxes, 'on')
            title(app.UIAxes, 'fiber results');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            plot(app.UIAxes,app.intersectionTable(:,1),app.intersectionTable(:,2),'b.','MarkerSize',15);
            app.UITable.Data = tdata;
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            IP = app.intersectionTable;
            uisave('IP','intersection_points')
        end

        % Value changed function: pointsnotcloserthanEditField
        function pointsnotcloserthanEditFieldValueChanged(app, event)
            app.value = app.pointsnotcloserthanEditField.Value;
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            IP = app.intersectionTable;
            sizeIMG = app.size;
            distance = app.value;
            app.intersectionTable = ipCombineRegions(IP, sizeIMG, distance);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.deleteButton = uibutton(app.UIFigure, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = {'delete'; ''};
            app.clearpointButton = uibutton(app.UIFigure, 'push');
            app.clearpointButton.ButtonPushedFcn = createCallbackFcn(app, @clearpointButtonPushed, true);
            app.clearpointButton.Text = {'clear point'; ''};
            app.exportButton = uibutton(app.UIFigure, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = {'export'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.refreshButton = uibutton(app.UIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.pointsnotcloserthanEditFieldLabel = uilabel(app.UIFigure);
            app.pointsnotcloserthanEditFieldLabel.Text = {'points not closer than:'; ''};
            app.pointsnotcloserthanEditField = uieditfield(app.UIFigure, 'numeric');
            app.pointsnotcloserthanEditField.ValueChangedFcn = createCallbackFcn(app, @pointsnotcloserthanEditFieldValueChanged, true);
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = {'calculate'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = intersectionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end